classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        OutputPanel                matlab.ui.container.Panel
        TextArea                   matlab.ui.control.TextArea
        TextAreaLabel              matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        UserInputsPanel            matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        Range6500yrage1000yrLabel  matlab.ui.control.Label
        Range550mweight750kgLabel  matlab.ui.control.Label
        EnterAgeEditField          matlab.ui.control.NumericEditField
        EnterAgeEditFieldLabel     matlab.ui.control.Label
        EnterWeightEditField       matlab.ui.control.NumericEditField
        EnterWeightEditFieldLabel  matlab.ui.control.Label
        Range100mheight200mLabel   matlab.ui.control.Label
        EnterHeightEditField       matlab.ui.control.NumericEditField
        EnterHeightEditFieldLabel  matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        GenerateDesignButton       matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        user_data % struct containing user_data
        output % output log file data
        output_log_path = "output.txt" % path to output log file
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EnterHeightEditField
        function EnterHeightEditFieldValueChanged(app, event)
            value = app.EnterHeightEditField.Value;
            app.user_data.height = value;
        end

        % Value changed function: EnterWeightEditField
        function EnterWeightEditFieldValueChanged(app, event)
            value = app.EnterWeightEditField.Value;
            app.user_data.weight = value;
        end

        % Value changed function: EnterAgeEditField
        function EnterAgeEditFieldValueChanged(app, event)
            value = app.EnterAgeEditField.Value;
            app.user_data.age = value;
        end

        % Button pushed function: GenerateDesignButton
        function GenerateDesignButtonPushed(app, event)
            fileID = fopen(app.output_log_path, 'w');
            
            app.output = fileread(app.output_log_path);
            app.TextArea.Value = app.output;
            system_design(app.user_data);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {382, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img_tmp.jpg');
            app.OutputPanel = uipanel(app.GridLayout5);
            app.OutputPanel.Title = 'Output';
            app.TextAreaLabel = uilabel(app.OutputPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.OutputPanel);
            app.TextArea.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ExitButton = uibutton(app.GridLayout3, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.GenerateDesignButton = uibutton(app.GridLayout3, 'push');
            app.GenerateDesignButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDesignButtonPushed, true);
            app.GenerateDesignButton.Text = 'Generate Design';
            app.UserInputsPanel = uipanel(app.GridLayout2);
            app.UserInputsPanel.Title = 'User Inputs:';
            app.GridLayout4 = uigridlayout(app.UserInputsPanel);
            app.EnterHeightEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterHeightEditFieldLabel.Text = 'Enter Height:';
            app.EnterHeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterHeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterHeightEditFieldValueChanged, true);
            app.Range100mheight200mLabel = uilabel(app.GridLayout4);
            app.Range100mheight200mLabel.Text = 'Range --> [1.00m <= height <= 2.00m]';
            app.EnterWeightEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterWeightEditFieldLabel.Text = 'Enter Weight:';
            app.EnterWeightEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterWeightEditField.ValueChangedFcn = createCallbackFcn(app, @EnterWeightEditFieldValueChanged, true);
            app.EnterAgeEditFieldLabel = uilabel(app.GridLayout4);
            app.EnterAgeEditFieldLabel.Text = 'Enter Age:';
            app.EnterAgeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EnterAgeEditField.ValueChangedFcn = createCallbackFcn(app, @EnterAgeEditFieldValueChanged, true);
            app.Range550mweight750kgLabel = uilabel(app.GridLayout4);
            app.Range550mweight750kgLabel.Text = 'Range --> [55.0m <= weight <= 75.0kg]';
            app.Range6500yrage1000yrLabel = uilabel(app.GridLayout4);
            app.Range6500yrage1000yrLabel.Text = 'Range --> [65.00yr <= age <= 100.0yr]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end